classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_16    matlab.ui.control.Button
        Button_15    matlab.ui.control.Button
        Button_14    matlab.ui.control.Button
        Button_13    matlab.ui.control.Button
        Button_12    matlab.ui.control.Button
        Button_11    matlab.ui.control.Button
        TextArea     matlab.ui.control.TextArea
        Button_9     matlab.ui.control.Button
        Button_8     matlab.ui.control.Button
        Button_7     matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        ContextMenu  matlab.ui.container.ContextMenu
        Menu         matlab.ui.container.Menu
        Menu2        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        valueA = 0 % first
        valueB = 0 % second
        pocetKlikovValue = 0
        pocetKlikovNzmber = 1
        operationUsed = 0
        answ % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function klik(app, event)
       
        end

        % Button pushed function: Button_7
        function addition(app, event)
            if app.pocetKlikovValue == 0
                app.valueA = app.valueA * 10 + 7;
                app.TextArea.Value = num2str(app.valueA);
            else 
                app.valueB = app.valueB * 10 + 7;
                app.TextArea.Value = num2str(app.valueB);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.pocetKlikovValue == 0
                app.valueA = app.valueA * 10 + 1;
                app.TextArea.Value = num2str(app.valueA);
            else 
                app.valueB = app.valueB * 10 + 1;
                app.TextArea.Value = num2str(app.valueB);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.pocetKlikovValue == 0
                app.valueA = app.valueA * 10 + 2;
                app.TextArea.Value = num2str(app.valueA);
            else 
                app.valueB = app.valueB * 10 + 2;
                app.TextArea.Value = num2str(app.valueB);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.pocetKlikovValue == 0
                app.valueA = app.valueA * 10 + 3;
                app.TextArea.Value = num2str(app.valueA);
            else 
                app.valueB = app.valueB * 10 + 3;
                app.TextArea.Value = num2str(app.valueB);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.pocetKlikovValue == 0
                app.valueA = app.valueA * 10 + 4;
                app.TextArea.Value = num2str(app.valueA);
            else 
                app.valueB = app.valueB * 10 + 4;
                app.TextArea.Value = num2str(app.valueB);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.pocetKlikovValue == 0
                app.valueA = app.valueA * 10 + 5;
                app.TextArea.Value = num2str(app.valueA);
            else 
                app.valueB = app.valueB * 10 + 5;
                app.TextArea.Value = num2str(app.valueB);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.pocetKlikovValue == 0
                app.valueA = app.valueA * 10 + 6;
                app.TextArea.Value = num2str(app.valueA);
            else 
                app.valueB = app.valueB * 10 + 6;
                app.TextArea.Value = num2str(app.valueB);
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
           if app.pocetKlikovValue == 0
                app.valueA = app.valueA * 10 + 8;
                app.TextArea.Value = num2str(app.valueA);
            else 
                app.valueB = app.valueB * 10 + 8;
                app.TextArea.Value = num2str(app.valueB);
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if app.pocetKlikovValue == 0
                app.valueA = app.valueA * 10 + 9;
                app.TextArea.Value = num2str(app.valueA);
            else 
                app.valueB = app.valueB * 10 + 9;
                app.TextArea.Value = num2str(app.valueB);
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.operationUsed = 1;
            app.pocetKlikovValue = 1;
            app.TextArea.Value = num2str(app.valueB);
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.operationUsed = 2;
            app.pocetKlikovValue = 1;
            app.TextArea.Value = num2str(app.valueB);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.operationUsed = 3;
            app.pocetKlikovValue = 1;
            app.TextArea.Value = num2str(app.valueB);
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.operationUsed = 4;
            app.pocetKlikovValue = 1;
            app.TextArea.Value = num2str(app.valueB);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if app.operationUsed == 1
                app.answ = app.valueA + app.valueB;
                app.TextArea.Value = num2str(app.answ);
            elseif app.operationUsed == 2
                app.answ = app.valueA - app.valueB;
                app.TextArea.Value = num2str(app.answ);
            elseif app.operationUsed == 3
                app.answ = app.valueA * app.valueB;
                app.TextArea.Value = num2str(app.answ);
            else
                app.answ = app.valueA / app.valueB;
                app.TextArea.Value = num2str(app.answ);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '2';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '1';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @addition, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '0';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '=';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '+';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '-';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '*';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '/';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end